classdef Interpolazione < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        InserireidatiLabel           matlab.ui.control.Label
        FunzioneLabel                matlab.ui.control.Label
        IntervalloLabel              matlab.ui.control.Label
        NodiLabel                    matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        EditField_sx                 matlab.ui.control.NumericEditField
        EditField_dx                 matlab.ui.control.NumericEditField
        EditField_nodo_1             matlab.ui.control.NumericEditField
        EstremoSinistroLabel         matlab.ui.control.Label
        EstremoDestroLabel           matlab.ui.control.Label
        STARTButton                  matlab.ui.control.Button
        Label_estremi                matlab.ui.control.Label
        NumeroNodiLabel              matlab.ui.control.Label
        TipodiFunzioneButtonGroup    matlab.ui.container.ButtonGroup
        splineButton                 matlab.ui.control.ToggleButton
        spline_naturaleButton        matlab.ui.control.ToggleButton
        polinomiale_trattiButton     matlab.ui.control.ToggleButton
        polinomialeButton            matlab.ui.control.ToggleButton
        ErroriLabel                  matlab.ui.control.Label
        Label_risultati              matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        InterpolazioneFunzioneLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        function [errore] =  interpolazione(app,funzione,tipo,a,b,n_nodi)
    
            %Creo un vettore per l'intervallo medianter linspace
            t = linspace(a,b); 
            
            
            y1 = funzione(t);
            
            % Definisco il vettore per le coordinate X e Y 
            
            x = linspace(a,b,n_nodi);
      
            y = funzione(x);


    switch tipo
        case 'polinomiale'


            % Utilizzando la funzione polyfit è possibile ottenere in uscita i coefficienti del 
            % polinomio fornendo in ingresso x , y e il numero di nodi 
            
            p = polyfit(x,y,n_nodi-1);
            
            % La funzione polyval valuta il vettore appena ottenuto in ogni punto x
            % Genera la curva 
            y_out = polyval(p,t);
            
            
            % x y punti, t y_out funzione interpolata, t y1 funzione reale
            %xlim([-10, 10])
            %ylim([0,0.8]) 
            plot(app.UIAxes,t,y1,'r',t,y_out,'b',x,y,'ok');
            legend(app.UIAxes,'Funzione','Interpolazione Polinomiale','Punti')
            title(app.UIAxes,'Interpolazione polinomiale')


        case 'lineare'


            % generiamo una funzione interpolante a tratti lineare, 
            % quindi dati due punti viene tracciato il segmento che congiunge questi due punti.
            y_out = interp1(x,y,t);
            
            %xlim([-10, 10])
            %ylim([0,0.8])
            plot(app.UIAxes,t,y1,'r',t,y_out,'b',x,y,'ok');    
            legend(app.UIAxes,'Funzione','Interpolazione Polinomiale a tratti','Punti')
            title(app.UIAxes,'Interpolazione polinomiale a tratti')


        case 'spline_not_a_knot'


            % La funzione spline fa un interpolazione not a knot cubica 
            y_out = spline(x,y,t);
            %xlim([-10, 10])
            %ylim([0,0.8])
            plot(app.UIAxes,t,y1,'r',t,y_out,'b',x,y,'ok');
            legend(app.UIAxes,'Funzione','Interpolazione Spline cubica','Punti')
            title(app.UIAxes,'Interpolazione Spline cubica')


       case 'spline_naturale'


            % Per poter interpolare utilizzando gli altri tipi di spline (naturale) dobbiamo richiamare quest'altra routine di matlab, csape.
            % Funzione come spline, in x e y i dati che abbiamo a disposizione, e l'output è pp-form, non possiamo inserire come nella spline i nodi dove valutare la funzione interpolante ottenuta.
            pp = csape(x,y,'variational')
            y_out = ppval(pp,t);
            %xlim([-10, 10])
            %ylim([0,0.8])
            plot(app.UIAxes,t,y1,'r',t,y_out,'b',x,y,'ok');
            legend(app.UIAxes,'Funzione','Interpolazione Spline Naturale','Punti')
            title(app.UIAxes,'Interpolazione Spline Naturale')


    end

    
    errore = norm(y_out-y1,inf);
      
end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                title = 'Informazioni utente';
            msg = "Testing interpolazione  ";
            selection = uiconfirm(app.UIFigure,msg,title,'Options',{'Inizia'},"CancelOption",1);
            
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            
            %Funzionalità per il pulsante di START
            
               app.Label_risultati.Visible = true
            
               funzione = str2func(['@(x)',app.EditField.Value]);
               
        
           if app.polinomialeButton.Value == true 
               tipo = 'polinomiale'
           end
             if app.polinomiale_trattiButton.Value == true 
               tipo = 'lineare'
             end
               if app.splineButton.Value == true 
               tipo = 'spline_not_a_knot'
               end
                 if app.spline_naturaleButton.Value == true 
               tipo = 'spline_naturale'
                 end
                  
                   
              a = app.EditField_sx.Value    
              b = app.EditField_dx.Value
              n_nodi = app.EditField_nodo_1.Value
               
            
      errore = interpolazione(app,funzione,tipo,a,b,n_nodi);
              app.ErroriLabel.Visible = true
              app.Label_risultati.Text = sprintf("%d" , errore) 


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {736, 736};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {295, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InserireidatiLabel = uilabel(app.LeftPanel);
            app.InserireidatiLabel.Text = 'Inserire i dati ';
            app.FunzioneLabel = uilabel(app.LeftPanel);
            app.FunzioneLabel.Text = {'Funzione'; ''; ''};
            app.IntervalloLabel = uilabel(app.LeftPanel);
            app.IntervalloLabel.Text = 'Intervallo ';
            app.NodiLabel = uilabel(app.LeftPanel);
            app.NodiLabel.Text = 'Nodi';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.EditField_sx = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_dx = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_nodo_1 = uieditfield(app.LeftPanel, 'numeric');
            app.EstremoSinistroLabel = uilabel(app.LeftPanel);
            app.EstremoSinistroLabel.Text = 'Estremo Sinistro';
            app.EstremoDestroLabel = uilabel(app.LeftPanel);
            app.EstremoDestroLabel.Text = 'Estremo Destro';
            app.STARTButton = uibutton(app.LeftPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = {'START'; ''};
            app.Label_estremi = uilabel(app.LeftPanel);
            app.Label_estremi.Visible = 'off';
            app.NumeroNodiLabel = uilabel(app.LeftPanel);
            app.NumeroNodiLabel.Text = 'Numero Nodi';
            app.TipodiFunzioneButtonGroup = uibuttongroup(app.LeftPanel);
            app.TipodiFunzioneButtonGroup.Title = 'Tipo di Funzione';
            app.splineButton = uitogglebutton(app.TipodiFunzioneButtonGroup);
            app.splineButton.Text = 'Spline';
            app.splineButton.Value = true;
            app.spline_naturaleButton = uitogglebutton(app.TipodiFunzioneButtonGroup);
            app.spline_naturaleButton.Text = 'Spline Naturale';
            app.polinomiale_trattiButton = uitogglebutton(app.TipodiFunzioneButtonGroup);
            app.polinomiale_trattiButton.Text = 'Polinomiale a tratti';
            app.polinomialeButton = uitogglebutton(app.TipodiFunzioneButtonGroup);
            app.polinomialeButton.Text = 'Polinomiale';
            app.ErroriLabel = uilabel(app.LeftPanel);
            app.ErroriLabel.Text = 'Errori';
            app.Label_risultati = uilabel(app.LeftPanel);
            app.Label_risultati.Text = {''; ''};
            app.RightPanel = uipanel(app.GridLayout);
            app.InterpolazioneFunzioneLabel = uilabel(app.RightPanel);
            app.InterpolazioneFunzioneLabel.Text = 'Interpolazione Funzione';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, {'Interpolazione'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interpolazione

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end