classdef crescitademografica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ButtonGroup                     matlab.ui.container.ButtonGroup
        GraficosplineButton             matlab.ui.control.ToggleButton
        GraficoPredittivoButton         matlab.ui.control.ToggleButton
        RettaminimiquadratiButton       matlab.ui.control.ToggleButton
        GestioneErroreButton            matlab.ui.control.ToggleButton
        CambioEuroDollaro19992020Label  matlab.ui.control.Label
        CrescitademograficaItalia19462020Label  matlab.ui.control.Label
        ESEGUIButton                    matlab.ui.control.Button
        SMOOTHINGLabel                  matlab.ui.control.Label
    end

    properties (Access = public)
        fullname % Description
    end
    
    methods (Access = public)
        
       function DataSmoothing(app)
            
            vettore=dlmread('censimento.txt');

            anni = vettore(:,1); %Prima colonna
            censimento = vettore(:,2); %Seconda colonna
            
            %cens = zeros(1,19)
             cens = zeros(1,75);
             
            for i=2:75 %Parto dal 2° dato => Secondo - Primo e così via...
               
                %Calcoliamo quanto è l'incremento ogni anno
                cens(i) = censimento(i) - censimento(i-1);
                
            end
            
            %--Spline--
            
            %Passo campionamento SPLINE 
            t = 1946 : .1 : 2020;
            
            s = spline(anni , cens , t); 
            
            if app.GraficosplineButton.Value == true 
                
                 plot( anni , cens , 'o' ,  t , s  );
                 title('Interpolazione Spline');
                 xlabel( 'Anni');
                 ylabel('Variazione Popolazione');
                 legend('Dati','Interpolazione con spline');
                
                
            elseif app.GraficoPredittivoButton.Value == true
                popolazione_prevista = [spline(anni , censimento , 2021)]; %Crescita Prevista mediante SPLINE 
                anniX = [anni , 
                        2021];
                crescita_prevista=popolazione_prevista-censimento(end);
                censX = [cens , crescita_prevista];
                t = 1946 : .1 : 2021; 
                sX = spline(anniX , censX , t);
                figure(2);
                plot(anniX , censX , 'o' , t , sX);
                title('Spline : Crescita 2021 predittiva');
                 xlabel( 'Anni');
                 ylabel('Variazione Popolazione');
                 legend('Dati','Interpolazione con spline');
                 
            elseif app.RettaminimiquadratiButton.Value == true  %Smoothing approssimazione 
                
                
                %(1) Retta minimi quadrati 
                t = 1946 : .1 : 2020 ;
                % determino i coefficienti dellla retta
                p = polyfit(anni , cens , 1);
                ss = polyval(p , t);
                figure(3);
                plot(anni , cens , 'o' , t , ss);
                title('Smoothing : Retta minimi quadrati');
                xlabel( 'Anni');
                ylabel('Variazione Popolazione');
                legend('Dati','retta minimi quadrati');
                
                %Rimossa poiché veniva ridotta ad una retta a causa del
                %range di valori troppo ampio
                
                %(2) Parabola minimi quadrati
                %t = 1946 : .1 : 2020 ;
                % determino i coefficienti dellla retta
                %p = polyfit(anni , cens , 2);
                %ss = polyval(p , t);
                
                %figure(4);
                %plot(anni , cens , 'o' , t , ss);
                %title('Smoothing : Parabola minimi quadrati');
                %xlabel( 'Anni');
                %ylabel('Popolazione totale');
                %legend('Dati','Parabola minimi quadrati');
                 
            else %Smoothing gestione dell'errore 
                
                 %Nascite dal 2001 al 2019 
                 
                 %GRAFICO CORRETTO 
                 vettore2=dlmread('cambio_euro_dollaro_giusto.txt');
                 
                 anniNascita1       = vettore2(:,1); %Prima colonna
                 censimentoNascita1 = vettore2(:,2); %Seconda colonna
            
                  
                 censN = zeros(1,22)
             
            for i=2:22 %Parto dal 2° dato => Secondo - Primo e così via...
               
                %Calcoliamo quanto è l'incremento ogni anno
                censN(i) = censimentoNascita1(i) - censimentoNascita1(i-1);
                
            end
                 
                 t = 1999 : .1 : 2020;
                 p = polyfit(anniNascita1 , censN , 1);
                 ss = polyval(p , t);
                 
                 
                 figure(5);
                 plot(anniNascita1 , censN , 'o' , t , ss);
                 title('Smoothing (retta) : Cambio EURO - DOLLARO 1999/2020 senza errore');
                 xlabel('Anni');
                 ylabel('Tasso di Cambio');
                 legend('Dati' , 'Retta Minimi Quadrati')
                
                 %GRAFICO CON ERRORE
                 
                 vettore3=dlmread('cambio_euro_dollaro_errori.txt');
                 
                 anniNascita2       = vettore3(:,1); %Prima colonna
                 censimentoNascita2 = vettore3(:,2); %Seconda colonna
            
                  
                 censN2 = zeros(1,22)
             
                for i=2:22 %Parto dal 2° dato => Secondo - Primo e così via...
               
                %Calcoliamo quanto è l'incremento ogni anno
                censN2(i) = censimentoNascita2(i) - censimentoNascita2(i-1);
                
                end
                 
                 t1 = 1999 : .1 : 2020;
                 p1 = polyfit(anniNascita2 , censN2 , 1);
                 ss1 = polyval(p1 , t1);
                 
                  
                 figure(6);
                 plot(anniNascita2 , censN2 , 'o' , t1 , ss1);
                 title('Smoothing (retta) : Cambio EURO - DOLLARO 1999/2020 con errore');
                 xlabel('Anni');
                 ylabel('Tasso di cambio');
                 legend('Dati' , 'Retta Minimi Quadrati')
                
                
            end
    end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
       
              title = 'Informazioni utente';
            msg = "Con tale feature si è in grado di valutare la crescita demografica avuta in italia dal 1946 al 2020 e" + ...
                "  anche il tasso di cambio Euro/Dollaro dal 1999 al 2020 per evidenziare la gestione degli errori del metodo di Smoothing " + ...
                "  ";
            selection = uiconfirm(app.UIFigure,msg,title,'Options',{'Inizia'},"CancelOption",1);
          
       
        end

        % Button pushed function: ESEGUIButton
        function ESEGUIButtonPushed(app, event)
            DataSmoothing(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.GraficosplineButton = uitogglebutton(app.ButtonGroup);
            app.GraficosplineButton.Text = 'Grafico spline';
            app.GraficosplineButton.Value = true;
            app.GraficoPredittivoButton = uitogglebutton(app.ButtonGroup);
            app.GraficoPredittivoButton.Text = 'Grafico Predittivo';
            app.RettaminimiquadratiButton = uitogglebutton(app.ButtonGroup);
            app.RettaminimiquadratiButton.Text = {'Retta minimi '; 'quadrati'};
            app.GestioneErroreButton = uitogglebutton(app.ButtonGroup);
            app.GestioneErroreButton.Text = 'Gestione Errore';
            app.CambioEuroDollaro19992020Label = uilabel(app.ButtonGroup);
            app.CambioEuroDollaro19992020Label.Text = {'Cambio Euro-Dollaro '; '1999/2020'};
            app.CrescitademograficaItalia19462020Label = uilabel(app.ButtonGroup);
            app.CrescitademograficaItalia19462020Label.Text = {'Crescita demografica  '; 'Italia 1946/2020'};
            app.ESEGUIButton = uibutton(app.UIFigure, 'push');
            app.ESEGUIButton.ButtonPushedFcn = createCallbackFcn(app, @ESEGUIButtonPushed, true);
            app.ESEGUIButton.Text = 'ESEGUI';
            app.SMOOTHINGLabel = uilabel(app.UIFigure);
            app.SMOOTHINGLabel.Text = 'SMOOTHING ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crescitademografica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end