classdef tracking_miglioria < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InterpolazioneImmagineLabel     matlab.ui.control.Label
        TracciamentoimmagineButton      matlab.ui.control.Button
        NumeroNodiEditFieldLabel        matlab.ui.control.Label
        NumeroNodiEditField             matlab.ui.control.NumericEditField
        SelezionarenumerodinodiLabel    matlab.ui.control.Label
        CaricaimmaginedatracciareLabel  matlab.ui.control.Label
        ResizeImmagineLabel             matlab.ui.control.Label
        CambiaDimensioneButton          matlab.ui.control.Button
        ColonneEditFieldLabel           matlab.ui.control.Label
        RigheEditFieldLabel             matlab.ui.control.Label
        RigheEditField                  matlab.ui.control.NumericEditField
        SelezionarelaRisoluzioneLabel   matlab.ui.control.Label
        CaricaimmaginedamodificareLabel  matlab.ui.control.Label
        ColonneEditField                matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            title = 'Informazioni utente';
            msg = "In questa sezione è possibile caricare un'immagine e selezionare manualmente i punti di interpolazione," + ...
                "dopo aver tracciato i punti verrà visualizzata la curva di interpolazione. " +... 
                "Inoltre si è in grado di ridimensionare l'immagine mediante tre metodi di interpolazione. ";
           uiconfirm(app.UIFigure,msg,title,'Options',{'Inizia'},"CancelOption",1);
            
         
        end

        % Button pushed function: TracciamentoimmagineButton
        function TracciamentoimmagineButtonPushed(app, event)
           
            %Carico numero nodi
            nodi_caricati = app.NumeroNodiEditField.Value;
            
            % Carico un'immagine dal disco 
            [filename, filepath] = uigetfile({'*.*';'*.jpg';'*.png';'*.bmp'}, "Cerca un'immagine da interpolare");
            fullname = [filepath filename];
           
            try
                %Leggo l'immagine caricata in fullname
                immagine = imread(fullname);
                immagineScalata = image(immagine);
                colormap gray;
                hold on
                % Vado ad inizializzare le variabili di appoggio che
                % utilizzo per il conteggio del numero dei nodi e per
                % salvare le coordinate di ogni punto selezionato
                num_nodi = 0;
                x_punto = [];
                y_punto= [];


                % Inizializzo la variabile che indica la pressione del
                % tasto sinistro del mouse
                pulsante = 1;
                
                
                %Ciclo finché non raggiungo il numero di nodi definito
                while (num_nodi ~= nodi_caricati)
                    
                    
                    % Prendo le coordinate dei punti selezionati 
                    [xPos, yPos, pulsante] = ginput(1);
                    
                    % Disegno il punto
                    plot(xPos, yPos, '*', 'LineWidth', 1.5);
                    
                    
                    %Numero Nodi disegnati
                    num_nodi = num_nodi + 1;
                    
                    %Salvataggio Coordinate 
                    x_punto = [x_punto xPos];
                    y_punto = [y_punto yPos];
                end
                
                % Vado a definire i parametri per la spline
                iteratore = 1 : num_nodi;
                lin_space = linspace(1, num_nodi);
                x_spline = spline(iteratore, x_punto, lin_space);
                y_spline = spline(iteratore, y_punto, lin_space);
             
               
                % Schiarisco l'immagine
                set(immagineScalata, 'alphadata', .4);
                plot(x_spline, y_spline, 'LineWidth', 1.5);


                hold off
            catch
            end
        end

        % Button pushed function: CambiaDimensioneButton
        function CambiaDimensioneButtonPushed(app, event)


            [filename, filepath] = uigetfile({'*.*';'*.jpg';'*.png';'*.bmp'}, "Cerca un'immagine da interpolare");
            fullname = [filepath filename];
            
            immagine = imread(fullname);
          
           
            bilineare = imresize(immagine , [app.RigheEditField.Value , app.ColonneEditField.Value] , 'bilinear');
            imwrite(bilineare , 'bilineare.jpg')
            
            bicubica = imresize(immagine , [app.RigheEditField.Value , app.ColonneEditField.Value] , 'bicubic');
            imwrite(bicubica , 'bicubica.jpg')
            
            nearest = imresize(immagine , [app.RigheEditField.Value , app.ColonneEditField.Value] , 'nearest');
            imwrite(nearest , 'nearest.jpg')


            figure("Name", "Immagine Originale") 
            imshow(immagine)
            
            
            figure("Name" , "Immagine con interpolazione Bilineare")
            imshow(bilineare)
            
            
            figure("Name" , "Immagine con interpolazione Bicubica")
            imshow(bicubica)
            
            figure("Name" , "Immagine con interpolazione Nearest")
            imshow(nearest)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InterpolazioneImmagineLabel = uilabel(app.UIFigure);
            app.InterpolazioneImmagineLabel.Text = {'Interpolazione Immagine'; ''};
            app.TracciamentoimmagineButton = uibutton(app.UIFigure, 'push');
            app.TracciamentoimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @TracciamentoimmagineButtonPushed, true);
            app.TracciamentoimmagineButton.Text = 'Tracciamento immagine';
            app.NumeroNodiEditFieldLabel = uilabel(app.UIFigure);
            app.NumeroNodiEditFieldLabel.Text = 'Numero Nodi';
            app.NumeroNodiEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelezionarenumerodinodiLabel = uilabel(app.UIFigure);
            app.SelezionarenumerodinodiLabel.Text = 'Selezionare numero di nodi';
            app.CaricaimmaginedatracciareLabel = uilabel(app.UIFigure);
            app.CaricaimmaginedatracciareLabel.Text = 'Carica immagine da tracciare';
            app.ResizeImmagineLabel = uilabel(app.UIFigure);
            app.ResizeImmagineLabel.Text = 'Resize Immagine';
            app.CambiaDimensioneButton = uibutton(app.UIFigure, 'push');
            app.CambiaDimensioneButton.ButtonPushedFcn = createCallbackFcn(app, @CambiaDimensioneButtonPushed, true);
            app.CambiaDimensioneButton.Text = 'Cambia Dimensione';
            app.ColonneEditFieldLabel = uilabel(app.UIFigure);
            app.ColonneEditFieldLabel.Text = 'Colonne';
            app.RigheEditFieldLabel = uilabel(app.UIFigure);
            app.RigheEditFieldLabel.Text = 'Righe';
            app.RigheEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelezionarelaRisoluzioneLabel = uilabel(app.UIFigure);
            app.SelezionarelaRisoluzioneLabel.Text = 'Selezionare la Risoluzione';
            app.CaricaimmaginedamodificareLabel = uilabel(app.UIFigure);
            app.CaricaimmaginedamodificareLabel.Text = 'Carica immagine da modificare';
            app.ColonneEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tracking_miglioria

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end